classdef SignalPanner_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalPannerDemoUIFigure  matlab.ui.Figure
        HelpLabel                 matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        TitleLabel                matlab.ui.control.Label
        FullViewButton            matlab.ui.control.Button
        ZoomInButton              matlab.ui.control.Button
        ZoomOutButton             matlab.ui.control.Button
        SignalPanner              SignalPanner
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Copyright 2026 The MathWorks, Inc.

    properties (Access = private)
        AxesLimitsListener event.proplistener
        IsSynchronizing (1,1) logical = false
    end

    methods (Access = private)

        function axesLimitsChanged(app)
            if app.IsSynchronizing || isempty(app.SignalPanner) || ...
                    ~isvalid(app.SignalPanner)
                return
            end

            app.IsSynchronizing = true;
            cleanup = onCleanup(@()finishSynchronization(app));
            app.SignalPanner.ViewLimits = app.UIAxes.XLim;
            updateStatus(app, app.SignalPanner.ViewLimits, "axes");
            clear cleanup
        end

        function synchronizeAxes(app, limits)
            if app.IsSynchronizing
                return
            end

            app.IsSynchronizing = true;
            cleanup = onCleanup(@()finishSynchronization(app));
            app.UIAxes.XLim = limits;
            clear cleanup
        end

        function zoomView(app, scaleFactor)
            app.SignalPanner.zoomBy(scaleFactor);
            synchronizeAxes(app, app.SignalPanner.ViewLimits);
            updateStatus(app, app.SignalPanner.ViewLimits, "zoom");
        end

        function updateStatus(app, limits, interaction)
            app.StatusLabel.Text = sprintf( ...
                "%s  |  %.3f - %.3f s", interaction, limits(1), limits(2));
        end

        function finishSynchronization(app)
            app.IsSynchronizing = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SignalPannerDemoUIFigure,"center")

            time = linspace(0, 12, 24000).';
            chirpSignal = sin(2*pi*(2.2*time + 0.19*time.^2));
            burstEnvelope = 0.25 + ...
                0.75 * exp(-0.5*((time - 7.2)/0.8).^2);
            burstSignal = burstEnvelope .* cos(2*pi*7.5*time);
            signals = [chirpSignal burstSignal];

            plot(app.UIAxes, time, signals, "LineWidth", 1);
            legend(app.UIAxes, ["Swept tone" "Burst"], ...
                "Location", "northeast");
            xlim(app.UIAxes, [1.5 4.0]);

            app.SignalPanner.setData(time, signals);
            app.SignalPanner.ViewLimits = [1.5 4.0];

            app.AxesLimitsListener = addlistener( ...
                app.UIAxes, "XLim", "PostSet", ...
                @(~,~)axesLimitsChanged(app));
            updateStatus(app, app.SignalPanner.ViewLimits, "ready");
        end

        % Callback function: SignalPanner
        function SignalPannerViewChanging(app, event)
            synchronizeAxes(app, event.ViewLimits);
            updateStatus(app, event.ViewLimits, event.Interaction);
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            zoomView(app, 2);
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            zoomView(app, 0.5);
        end

        % Button pushed function: FullViewButton
        function FullViewButtonPushed(app, event)
            app.SignalPanner.resetView();
            synchronizeAxes(app, app.SignalPanner.ViewLimits);
            updateStatus(app, app.SignalPanner.ViewLimits, "full view");
        end

        % Callback function: SignalPanner
        function SignalPannerViewChanged(app, event)
            synchronizeAxes(app, event.ViewLimits);
            updateStatus(app, event.ViewLimits, event.Interaction);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalPannerDemoUIFigure = uifigure('Visible', 'off');
            app.SignalPannerDemoUIFigure.Name = 'Signal Panner Demo';
            app.UIAxes = uiaxes(app.SignalPannerDemoUIFigure);
            title(app.UIAxes, 'Signals')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.SignalPanner = SignalPanner(app.SignalPannerDemoUIFigure);
            app.SignalPanner.ViewLimits = [0 1];
            app.SignalPanner.ViewChangingFcn = createCallbackFcn(app, @SignalPannerViewChanging, true);
            app.SignalPanner.ViewChangedFcn = createCallbackFcn(app, @SignalPannerViewChanged, true);
            app.ZoomOutButton = uibutton(app.SignalPannerDemoUIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.ZoomInButton = uibutton(app.SignalPannerDemoUIFigure, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.FullViewButton = uibutton(app.SignalPannerDemoUIFigure, 'push');
            app.FullViewButton.ButtonPushedFcn = createCallbackFcn(app, @FullViewButtonPushed, true);
            app.FullViewButton.Text = 'Full View';
            app.TitleLabel = uilabel(app.SignalPannerDemoUIFigure);
            app.TitleLabel.Text = 'Signal Panner Demo';
            app.StatusLabel = uilabel(app.SignalPannerDemoUIFigure);
            app.StatusLabel.Text = '';
            app.HelpLabel = uilabel(app.SignalPannerDemoUIFigure);
            app.HelpLabel.Text = 'Drag | resize edges | wheel';
            % Show the figure after all components are created
            app.SignalPannerDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalPanner_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalPannerDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalPannerDemoUIFigure)
        end
    end
end